classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SmartStethoscopeLabel  matlab.ui.control.Label
        SaveHSButton           matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global r;
            global Fs;
            global bits;
            global A;

            Fs = 8000; %sampling frequency
            ch = 1;
            bits = 16;
            r = audiorecorder(Fs, bits, ch);
            
            msgbox('Recording Started');
            recordblocking(r,5);   
            msgbox('Recording Stopped');
            audio = getaudiodata(r);

            dB30 = db2mag(30);

            A = audio*dB30;

            plot(audio,'Parent', app.UIAxes);
            plot(A,'Parent', app.UIAxes_2);


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %Value = app.PlayButton.Value;
            global A;
            global Fs;
            global bits;
            
            sound(A,Fs,bits);   
        end

        % Button pushed function: SaveHSButton
        function SaveHSButtonPushed(app, event)
            global A;
            global Fs;

           
            [filename, filepath] = uiputfile({'*.wav';'*.mp3'}, 'Save Audio as .wav');

            if filename ~= 0 
                fullFilePath = fullfile(filepath, filename);
                audiowrite(fullFilePath, A, Fs); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Amplified signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            colormap(app.UIAxes_2, 'parula')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.SaveHSButton = uibutton(app.UIFigure, 'push');
            app.SaveHSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHSButtonPushed, true);
            app.SaveHSButton.Text = 'Save HS';
            app.SmartStethoscopeLabel = uilabel(app.UIFigure);
            app.SmartStethoscopeLabel.Text = 'Smart Stethoscope';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end